classdef gui_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EngineMountOptimizationToolboxLabel  matlab.ui.control.Label
        byNVHteamLabel                matlab.ui.control.Label
        SharifUniversityofTechnologyLabel  matlab.ui.control.Label
        Step1SetinputparametersPanel  matlab.ui.container.Panel
        NewEditButton                 matlab.ui.control.Button
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        LoadButton                    matlab.ui.control.Button
        Step2OptimizationPanel        matlab.ui.container.Panel
        NameEditField_2Label          matlab.ui.control.Label
        NameEditField_2               matlab.ui.control.EditField
        EditButton                    matlab.ui.control.Button
        LoadButton_2                  matlab.ui.control.Button
        NewButton                     matlab.ui.control.Button
        STARTButton                   matlab.ui.control.Button
        Step3ViewResultsPanel         matlab.ui.container.Panel
        NameEditField_3Label          matlab.ui.control.Label
        NameEditField_3               matlab.ui.control.EditField
        ViewResultsButton             matlab.ui.control.Button
        LoadButton_3                  matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        
        app_main
        item_input = 'not selected' % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             img = imread('splash.jpg');  %# A sample image to display
            %             jimg = im2java(img);
            %             frame = javax.swing.JFrame;
            %             frame.setUndecorated(true);
            %             icon = javax.swing.ImageIcon(jimg);
            %             label = javax.swing.JLabel(icon);
            %             frame.getContentPane.add(label);
            %             frame.pack;
            %             screenSize = get(0,'ScreenSize');  %# Get the screen size from the root object
            %             %             frame.setSize(screenSize(3)/2,screenSize(4)/2);
            %             frame.setLocation(screenSize(3)/2-300,screenSize(4)/2-200);
            %             frame.show;
            %             pause(3);
            %             frame.hide
            load('gui_def');
            gui_curr = gui_def;
            
            save('gui_curr.mat','gui_curr');
            RefreshButtonPushed(app)
            
            %               func3(app)
        end

        % Button pushed function: NewEditButton
        function GoStep1ButtonPushed(app, event)
            gui_input_parameters
        end

        % Button pushed function: EditButton
        function GoStep2ButtonPushed(app, event)
            gui_optimization;
        end

        % Button pushed function: ViewResultsButton
        function GoStep3ButtonPushed(app, event)
            gui_results;
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            gui_optinput
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            load('gui_curr')
            gui_curr.input_name
            app.NameEditField.Value = gui_curr.input_name;
            app.NameEditField_2.Value = gui_curr.opt_name;
        end

        % Callback function
        function Step1EditButton_2Pushed(app, event)
            gui_input_parameters
        end

        % Button pushed function: LoadButton
        function step1LoadButtonPushed(app, event)
            
            gui_loadinput;
            %             disp(app.item_input)
            %             app.NameEditField.Value=app.item_input;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EngineMountOptimizationToolboxLabel = uilabel(app.UIFigure);
            app.EngineMountOptimizationToolboxLabel.Text = 'Engine Mount Optimization Toolbox';
            app.byNVHteamLabel = uilabel(app.UIFigure);
            app.byNVHteamLabel.Text = 'by NVH team';
            app.SharifUniversityofTechnologyLabel = uilabel(app.UIFigure);
            app.SharifUniversityofTechnologyLabel.Text = 'Sharif University of Technology';
            app.Step1SetinputparametersPanel = uipanel(app.UIFigure);
            app.Step1SetinputparametersPanel.Title = 'Step 1 : Set input parameters';
            app.NewEditButton = uibutton(app.Step1SetinputparametersPanel, 'push');
            app.NewEditButton.ButtonPushedFcn = createCallbackFcn(app, @GoStep1ButtonPushed, true);
            app.NewEditButton.Text = 'New / Edit';
            app.NameEditFieldLabel = uilabel(app.Step1SetinputparametersPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.Step1SetinputparametersPanel, 'text');
            app.NameEditField.Value = 'not selected';
            app.LoadButton = uibutton(app.Step1SetinputparametersPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @step1LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Step2OptimizationPanel = uipanel(app.UIFigure);
            app.Step2OptimizationPanel.Title = 'Step 2 : Optimization';
            app.NameEditField_2Label = uilabel(app.Step2OptimizationPanel);
            app.NameEditField_2Label.Text = 'Name:';
            app.NameEditField_2 = uieditfield(app.Step2OptimizationPanel, 'text');
            app.NameEditField_2.Value = 'not selected';
            app.EditButton = uibutton(app.Step2OptimizationPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @GoStep2ButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.LoadButton_2 = uibutton(app.Step2OptimizationPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.NewButton = uibutton(app.Step2OptimizationPanel, 'push');
            app.NewButton.Text = 'New';
            app.STARTButton = uibutton(app.Step2OptimizationPanel, 'push');
            app.STARTButton.Text = 'START';
            app.Step3ViewResultsPanel = uipanel(app.UIFigure);
            app.Step3ViewResultsPanel.Title = 'Step 3 : View Results';
            app.NameEditField_3Label = uilabel(app.Step3ViewResultsPanel);
            app.NameEditField_3Label.Text = 'Name:';
            app.NameEditField_3 = uieditfield(app.Step3ViewResultsPanel, 'text');
            app.NameEditField_3.Value = 'not selected';
            app.ViewResultsButton = uibutton(app.Step3ViewResultsPanel, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GoStep3ButtonPushed, true);
            app.ViewResultsButton.Text = 'View Results';
            app.LoadButton_3 = uibutton(app.Step3ViewResultsPanel, 'push');
            app.LoadButton_3.Text = 'Load';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'SHARIFlogo.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'SAIPAlogo.png';
            app.Button_2.Text = '';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end