classdef gui_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SelectResultsTab              matlab.ui.container.Tab
        AvailableResultsListBoxLabel  matlab.ui.control.Label
        AvailableResultsListBox       matlab.ui.control.ListBox
        AddResultButton               matlab.ui.control.Button
        SelectedResultsListBoxLabel   matlab.ui.control.Label
        SelectedResultsListBox        matlab.ui.control.ListBox
        RefreshButton                 matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        FrequencyButton               matlab.ui.control.Button
        MountForcesButton             matlab.ui.control.Button
        CGDisplacementsButton         matlab.ui.control.Button
        StaticDeflectionsButton       matlab.ui.control.Button
        MountLocationsTab             matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        SpinnerLabel                  matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        Button                        matlab.ui.control.StateButton
        UITable                       matlab.ui.control.Table
        MountLocationsButton          matlab.ui.control.Button
        MountStiffnessTab             matlab.ui.container.Tab
        UIAxes_stiff                  matlab.ui.control.UIAxes
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        MountStiffnessButton          matlab.ui.control.Button
        KEDTab                        matlab.ui.container.Tab
        KEDButton                     matlab.ui.control.Button
        FrequencyTab                  matlab.ui.container.Tab
        StaticDeflectionsTab          matlab.ui.container.Tab
        MountForcesTab                matlab.ui.container.Tab
        CGDisplacementsTab            matlab.ui.container.Tab
        ResultsLabel                  matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = private)
         % Description
         
         
    end

    properties (Access = public)
        n=0;%number of selected results
        items % cell array of selected items
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            RefreshButtonPushed(app);


        end

        % Button pushed function: AddResultButton
        function AddResultButtonPushed(app, event)
            app.n=app.n+1;
            app.SelectedResultsListBox.Items(app.n)= {app.AvailableResultsListBox.Value};
            app.items = app.SelectedResultsListBox.Items;
        end

        % Value changed function: AvailableResultsListBox
        function AvailableResultsListBoxValueChanged(app, event)


        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.SelectedResultsListBox.Items={''}
            
           app.n=0
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            
            
            k=size(app.items);
            
            for i=1:k(2)
              
               load(['SavedResults/',app.items{i}]);
               ax = figure; hold on;ax.Name = app.items{i}(1:end-4);
               h = eval(app.items{i}(1:end-4));
            
               M = [h.eng.mass*eye(3)  zeros(3,3); zeros(3,3) h.eng.inertia];
               f_nat_init = NF_Calculator(h.x_init,M);
               f_nat_opt = NF_Calculator(h.x_opt,M);
               
               gui_plot_freq(f_nat_init,f_nat_opt,h.lb_freq,h.ub_freq);
                
            end
            
        end

        % Button pushed function: KEDButton
        function KEDButtonPushed(app, event)
            %KED figure
            k=size(app.items);
            
            for i=1:k(2)
               
               load(['SavedResults/',app.items{i}]);
               ax = figure; hold on;ax.Name = app.items{i}(1:end-4);
               h = eval(app.items{i}(1:end-4));


               gui_plot_KED(h.KED.init,h.KED.opt,h.KED.per);
                
            end
        end

        % Button pushed function: MountForcesButton
        function MountForcesButtonPushed(app, event)
            figure(6);    hold on; %mount forces figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               
               Force_Plot(F_1_init,F_2_init,F_3_init,F_1_opt,F_2_opt,F_3_opt);
                
            end
        end

        % Callback function: MountLocationsButton
        function MountLocationsButtonPushed(app, event)
            figure(1);    hold on %figure of mount locations
            gui_plot_body
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               gui_plot_loc(h,rand(1,3));
                               
            end
            [q_TRA,~] = TRA_finder(h.eng.inertia,[0;1;0]); % finding TRA axis
            q_TRA = [0;0;0;q_TRA];
            TRA_plotter(q_TRA,h.sus.E_cm);


            legend(plot_legend);
        end

        % Button pushed function: MountStiffnessButton
        function MountStiffnessButtonPushed(app, event)
            
           
            figure(2);    hold on; %stiffness figure
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               gui_plot_stiff(h);
               
            
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            mat_files=dir('SavedResults/*_res.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.AvailableResultsListBox.Items(i)={mat_files(i).name(1:end-8)};
                
            end
            app.AvailableResultsListBox.Value = app.AvailableResultsListBox.Items(1);

        end

        % Value changed function: SelectedResultsListBox
        function SelectedResultsListBoxValueChanged(app, event)
            
            app.items = app.SelectedResultsListBox.Items;
            
        end

        % Button pushed function: StaticDeflectionsButton
        function StaticDeflectionsButtonPushed(app, event)
            figure(5);    hold on; %static deflections figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               [K, ~] = stiff_cal(x_opt1_f,eta);
               stc_opt = abs((K/1.5)^(-1)*[0;0;M(1,1)*9.81;0;0;0]);
               st_def_plot(delta_s,stc_opt);
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectResultsTab = uitab(app.TabGroup);
            app.SelectResultsTab.Title = 'Select Results';
            app.AvailableResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.AvailableResultsListBoxLabel.Text = 'Available Results';
            app.AvailableResultsListBox = uilistbox(app.SelectResultsTab);
            app.AvailableResultsListBox.Items = {};
            app.AvailableResultsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableResultsListBoxValueChanged, true);
            app.AvailableResultsListBox.Value = {};
            app.AddResultButton = uibutton(app.SelectResultsTab, 'push');
            app.AddResultButton.ButtonPushedFcn = createCallbackFcn(app, @AddResultButtonPushed, true);
            app.AddResultButton.Text = 'Add Result';
            app.SelectedResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.SelectedResultsListBoxLabel.Text = 'Selected Results';
            app.SelectedResultsListBox = uilistbox(app.SelectResultsTab);
            app.SelectedResultsListBox.Items = {};
            app.SelectedResultsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedResultsListBoxValueChanged, true);
            app.SelectedResultsListBox.Value = {};
            app.RefreshButton = uibutton(app.SelectResultsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DeleteButton = uibutton(app.SelectResultsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.FrequencyButton = uibutton(app.SelectResultsTab, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.MountForcesButton = uibutton(app.SelectResultsTab, 'push');
            app.MountForcesButton.ButtonPushedFcn = createCallbackFcn(app, @MountForcesButtonPushed, true);
            app.MountForcesButton.Text = 'Mount Forces';
            app.CGDisplacementsButton = uibutton(app.SelectResultsTab, 'push');
            app.CGDisplacementsButton.Text = 'CG Displacements';
            app.StaticDeflectionsButton = uibutton(app.SelectResultsTab, 'push');
            app.StaticDeflectionsButton.ButtonPushedFcn = createCallbackFcn(app, @StaticDeflectionsButtonPushed, true);
            app.StaticDeflectionsButton.Text = 'Static Deflections';
            app.MountLocationsTab = uitab(app.TabGroup);
            app.MountLocationsTab.Title = 'Mount Locations';
            app.UIAxes = uiaxes(app.MountLocationsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SpinnerLabel = uilabel(app.MountLocationsTab);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.MountLocationsTab);
            app.Button = uibutton(app.MountLocationsTab, 'state');
            app.Button.Text = 'Button';
            app.UITable = uitable(app.MountLocationsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MountLocationsButton = uibutton(app.MountLocationsTab, 'push');
            app.MountLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @MountLocationsButtonPushed, true);
            app.MountLocationsButton.Text = 'Mount Locations';
            app.MountStiffnessTab = uitab(app.TabGroup);
            app.MountStiffnessTab.Title = 'Mount Stiffness';
            app.UIAxes_stiff = uiaxes(app.MountStiffnessTab);
            title(app.UIAxes_stiff, 'Title')
            xlabel(app.UIAxes_stiff, 'X')
            ylabel(app.UIAxes_stiff, 'Y')
            app.UIAxes_stiff.XLim = [0 1];
            app.UIAxes_stiff.XLimMode = 'manual';
            app.UIAxes_stiff.YLim = [0 1];
            app.UIAxes_stiff.YLimMode = 'manual';
            app.UIAxes_stiff.CLim = [0 1];
            app.UIAxes_stiff.CLimMode = 'manual';
            app.UIAxes_stiff.XTick = [0 0.5 1];
            app.UIAxes_stiff.YTick = [0 0.5 1];
            app.SliderLabel = uilabel(app.MountStiffnessTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.MountStiffnessTab);
            app.MountStiffnessButton = uibutton(app.MountStiffnessTab, 'push');
            app.MountStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @MountStiffnessButtonPushed, true);
            app.MountStiffnessButton.Text = 'Mount Stiffness';
            app.KEDTab = uitab(app.TabGroup);
            app.KEDTab.Title = 'KED';
            app.KEDButton = uibutton(app.KEDTab, 'push');
            app.KEDButton.ButtonPushedFcn = createCallbackFcn(app, @KEDButtonPushed, true);
            app.KEDButton.Text = 'KED';
            app.FrequencyTab = uitab(app.TabGroup);
            app.FrequencyTab.Title = 'Frequency';
            app.StaticDeflectionsTab = uitab(app.TabGroup);
            app.StaticDeflectionsTab.Title = 'Static Deflections';
            app.MountForcesTab = uitab(app.TabGroup);
            app.MountForcesTab.Title = 'Mount Forces';
            app.CGDisplacementsTab = uitab(app.TabGroup);
            app.CGDisplacementsTab.Title = 'CG Displacements';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_results

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end